classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        PreSwitch               matlab.ui.control.Switch
        PreSwitchLabel          matlab.ui.control.Label
        EditFieldTextArea       matlab.ui.control.TextArea
        EditFieldTextAreaLabel  matlab.ui.control.Label
        BrowseButton            matlab.ui.control.Button
        ControlPanel            matlab.ui.container.Panel
        PageIndicatorField      matlab.ui.control.TextArea
        Label_2                 matlab.ui.control.Label
        EarliestButton          matlab.ui.control.Button
        PreviousButton          matlab.ui.control.Button
        NextButton              matlab.ui.control.Button
        LatestButton            matlab.ui.control.Button
        OutputButton            matlab.ui.control.Button
        PlayButton              matlab.ui.control.Button
        ModeSlider              matlab.ui.control.Slider
        Label                   matlab.ui.control.Label
        ColorModeDropDown       matlab.ui.control.DropDown
        ColorModeLabel          matlab.ui.control.Label
        Panel_2                 matlab.ui.container.Panel
        GridLayout3             matlab.ui.container.GridLayout
        LandUseButton           matlab.ui.control.Button
        SizeButton              matlab.ui.control.Button
        SpeedButton             matlab.ui.control.Button
        AbsoluteButton          matlab.ui.control.Button
        TimelapseButton         matlab.ui.control.Button
        SliderButton            matlab.ui.control.Button
        ViewButton              matlab.ui.control.Button
        MenuPanel               matlab.ui.container.Panel
        GridLayout2             matlab.ui.container.GridLayout
        AnalyzeButton           matlab.ui.control.Button
        LatestDropDown          matlab.ui.control.DropDown
        LatestDropDownLabel     matlab.ui.control.Label
        EarliestDropDown        matlab.ui.control.DropDown
        EarliestDropDownLabel   matlab.ui.control.Label
        UIAxes                  matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        ImageFolder          % Folder path selected by user
        ImageList            % Sorted list of image filenames
        AlignedImages        % Aligned/preprocessed image data
        TransformParams      % Registration parameters from preprocessing
        idxEarliest          % Index of the earliest selected image
        idxLatest            % Index of the latest selected image
        RGBButton
        GrayButton
        CurrentMode          char    
        FolderPathEditField  matlab.ui.control.EditField
        currentIndex = 1
        imgEarliest
        imgLatest
        EarliestLatestSubset     % Subset of images for paging
        PreprocessingEnabled logical = false;
        CurrentDetectionMode char
        DetectionResults struct

    end

    
   methods (Access = private) 

    function showModeControls(app, mode)
        % 先把所有模式控件都隐藏
        % =========================
        app.ColorModeDropDown.Visible = 'off';
        app.ModeSlider.Visible = 'off';
        app.PlayButton.Visible = 'off';

        app.EarliestButton.Visible = 'off';
        app.PreviousButton.Visible = 'off';
        app.NextButton.Visible = 'off';
        app.LatestButton.Visible = 'off';
        app.PageIndicatorField.Visible = 'off';


        % 根据当前模式显示需要的
        % =========================
        switch mode
            case 'Difference'
                app.ColorModeDropDown.Visible = 'on';

            case 'Slider'
                app.ModeSlider.Visible = 'on';
                % 缓存起始和结束图像
                app.imgEarliest = app.AlignedImages{app.idxEarliest};
                app.imgLatest = app.AlignedImages{app.idxLatest};

                % 设置slider到中间
                app.ModeSlider.Value = 50;

                % 初始显示
                app.updateSliderBlend();
                
            case 'Absolute'
                app.ModeSlider.Visible = 'on';

            case 'Time-lapse'
                if numel(app.AlignedImages) < 10
                    uialert(app.UIFigure, ...
                        'Need at least 10 images for time-lapse.', 'Error');
                else
                    app.PlayButton.Visible = 'on';
                end

            case 'EarliestLatest'
                
                app.EarliestButton.Visible = 'on';
                app.PreviousButton.Visible = 'on';
                app.NextButton.Visible = 'on';
                app.LatestButton.Visible = 'on';
                app.PageIndicatorField.Visible = 'on';
                app.currentIndex = 1;
                app.updateImageDisplay();

            case 'Speed'
                disp('Switched to SPEED detection mode.');

            case 'Size'
                disp('Switched to SIZE detection mode.');

            case 'LandUse'
                disp('Switched to LAND USE detection mode.');
        end

    end

    function updateImageDisplay(app)
        if strcmp(app.CurrentMode, 'EarliestLatest')
            if isempty(app.EarliestLatestSubset)
                cla(app.UIAxes);
                app.PageIndicatorField.Value = '0/0';
                return;
            end
            total = numel(app.EarliestLatestSubset);
        else
            total = numel(app.AlignedImages);
        end

        % 防止越界
        if app.currentIndex < 1
            app.currentIndex = 1;
        elseif app.currentIndex > total
            app.currentIndex = total;
        end

        % 显示图像
        if strcmp(app.CurrentMode, 'EarliestLatest')
            imshow(app.EarliestLatestSubset{app.currentIndex}, 'Parent', app.UIAxes);
        else
            imshow(app.AlignedImages{app.currentIndex}, 'Parent', app.UIAxes);
        end
        axis(app.UIAxes, 'off');

        % 更新文本
        app.PageIndicatorField.Value = sprintf('%d / %d', app.currentIndex, total);

    end

    function updateSliderBlend(app)
        % 获取滑块值（0到100）
        t = app.ModeSlider.Value / 100;

        % 做线性混合
        img1 = im2double(app.imgEarliest);
        img2 = im2double(app.imgLatest);

        % 确保大小一致
        if ~isequal(size(img1), size(img2))
            uialert(app.UIFigure, 'Images are not the same size.', 'Error');
            return;
        end

        blended = (1 - t) * img1 + t * img2;

        % 显示
        imshow(blended, 'Parent', app.UIAxes);
        axis(app.UIAxes, 'off');
    end

    function showDetectionResult(app, mode)
        mode = lower(mode);

        if ~isfield(app.DetectionResults, mode) || isempty(app.DetectionResults.(mode))
            uialert(app.UIFigure, sprintf('No detection result for mode: %s', mode), 'Error');
            return;
        end

        result = app.DetectionResults.(mode);

        % 优先显示 visual
        if isfield(result, 'visual') && ~isempty(result.visual)
            if iscell(result.visual)
                imshow(result.visual{1}, 'Parent', app.UIAxes);
            else
                imshow(result.visual, 'Parent', app.UIAxes);
            end
            axis(app.UIAxes, 'off');
            return;
        end

        % 如果有 heatmap
        if isfield(result, 'heatmap') && ~isempty(result.heatmap)
            imagesc(result.heatmap, 'Parent', app.UIAxes);
            axis(app.UIAxes, 'off');
            colorbar(app.UIAxes);
            return;
        end

        % 没有可视结果
        uialert(app.UIFigure, sprintf('No displayable content for mode: %s', mode), 'Error');
    end


end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            % Ask user to select a folder
            folderPath = uigetdir;
            if folderPath == 0
                uialert(app.UIFigure, 'No folder selected.', 'Error');
                return;
            end
            app.ImageFolder = folderPath;
            app.EditFieldTextArea.Value = folderPath;
            % Get all image files in the selected folder
            imgFormats = {'*.jpg','*.jpeg','*.png','*.tif','*.bmp'};
            files = [];
            for i = 1:length(imgFormats)
                files = [files; dir(fullfile(folderPath, imgFormats{i}))];
            end

            if isempty(files)
                uialert(app.UIFigure, 'No image files found in the selected folder.', 'Error');
                return;
            end

            % Extract filenames
            fileList = {files.name};

            sortedList = sort(fileList);

            % Store sorted list in app properties
            app.ImageList = sortedList;
            % Call the preprocessing module
            if app.PreprocessingEnabled
                disp('Preprocessing is ON. Calling registration module...');
                
                [app.AlignedImages, app.TransformParams] = register_images(folderPath, sortedList);

                % Placeholder: Just imread (since you don't have the module yet)
                % app.AlignedImages = cellfun(@(f) imread(fullfile(folderPath,f)), sortedList, 'UniformOutput', false);
                % app.TransformParams = [];

            else
                disp('Preprocessing is OFF. Just loading raw images...');
                app.AlignedImages = cellfun(@(f) imread(fullfile(folderPath,f)), sortedList, 'UniformOutput', false);
                app.TransformParams = [];
            end


            % Update dropdown menus
            app.EarliestDropDown.Items = sortedList;
            app.LatestDropDown.Items = sortedList;
            % Set default selections
            app.EarliestDropDown.Value = sortedList{1};
            app.LatestDropDown.Value = sortedList{end};

            % Record default idx
            app.idxEarliest = 1;
            app.idxLatest = numel(sortedList);

            % Feedback in command window
            disp('Images successfully imported :');
            disp(sortedList);
        end

        % Value changed function: EarliestDropDown
        function EarliestDropDownValueChanged(app, event)
           app.idxEarliest = find(strcmp(app.ImageList, app.EarliestDropDown.Value));
            
        end

        % Value changed function: LatestDropDown
        function LatestDropDownValueChanged(app, event)
            app.idxLatest = find(strcmp(app.ImageList, app.LatestDropDown.Value));
            
        end

        % Button pushed function: AnalyzeButton
        function AnalyzeButtonPushed(app, event)
            % === 1️⃣ 检查输入 ===
            if isempty(app.idxEarliest) || isempty(app.idxLatest)
                uialert(app.UIFigure, 'Please select both the earliest and latest images.', 'Error');
                return;
            end

            if app.idxLatest <= app.idxEarliest
                uialert(app.UIFigure, 'The latest image must be later than the earliest image.', 'Error');
                return;
            end

            % === 2️⃣ 取时间段图像 ===
            imagesForAnalysis = app.AlignedImages(app.idxEarliest : app.idxLatest);

            % === 3️⃣ 生成参数 ===
            options = struct();
            options.abs_threshold = 0.2;

            % === 4️⃣ 调用 detect_changes 并缓存 ===
            modes = {'absolute', 'speed', 'size', 'landuse'};
            app.DetectionResults = struct();

            for i = 1:numel(modes)
                try
                    disp(['Running detection for mode: ' modes{i}]);
                    result = detect_changes(imagesForAnalysis, modes{i}, options);
                    app.DetectionResults.(modes{i}) = result;
                catch ME
                    warning('Detection for mode %s failed: %s', modes{i}, ME.message);
                    app.DetectionResults.(modes{i}) = [];
                end
            end

            disp('✅ All detection modes processed and results stored.');

            % === 5️⃣ 默认显示 earliest ===
            imshow(imagesForAnalysis{1}, 'Parent', app.UIAxes);
            axis(app.UIAxes, 'off');
            app.currentIndex = 1;

            % === 6️⃣ 通知用户 ===
            uialert(app.UIFigure, 'Analysis complete. Use sidebar buttons to view results.', 'Ready');


        end

        % Button pushed function: SpeedButton
        function SpeedButtonPushed(app, event)
            app.CurrentMode = 'Speed';
            app.CurrentDetectionMode = 'speed';
            app.showModeControls('Speed');
            app.showDetectionResult('speed');

        end

        % Button pushed function: ViewButton
        function ViewButtonPushed(app, event)
            app.CurrentMode = 'EarliestLatest';

          
            if app.idxEarliest < app.idxLatest
                app.EarliestLatestSubset = app.AlignedImages(app.idxEarliest : app.idxLatest);
            else
                app.EarliestLatestSubset = {};  
            end

            app.currentIndex = 1;
            app.showModeControls('EarliestLatest');

            % earliest
            % imshow(app.AlignedImages{app.idxEarliest}, 'Parent', app.UIAxes);
        end

        % Button pushed function: SliderButton
        function SliderButtonPushed(app, event)
            app.CurrentMode = 'Slider';
            app.showModeControls('Slider');
        end

        % Button pushed function: AbsoluteButton
        function AbsoluteButtonPushed(app, event)
            app.CurrentMode = 'Absolute';
            app.CurrentDetectionMode = 'absolute';
            app.showModeControls('Absolute');
            app.showDetectionResult('absolute');
        end

        % Button pushed function: TimelapseButton
        function TimelapseButtonPushed(app, event)
            app.CurrentMode = 'Time-lapse';
            app.showModeControls('Time-lapse');
        end

        % Button pushed function: EarliestButton
        function EarliestButtonPushed(app, event)
            app.currentIndex = 1;
            app.updateImageDisplay();
        end

        % Button pushed function: PreviousButton
        function PreviousButtonPushed(app, event)
            if app.currentIndex > 1
                app.currentIndex = app.currentIndex - 1;
            end
            app.updateImageDisplay();
        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
            if strcmp(app.CurrentMode, 'EarliestLatest')
                maxCount = numel(app.EarliestLatestSubset);
            else
                maxCount = numel(app.AlignedImages);
            end

            if app.currentIndex < maxCount
                app.currentIndex = app.currentIndex + 1;
            end

            app.updateImageDisplay();
        end

        % Button pushed function: LatestButton
        function LatestButtonPushed(app, event)
            if strcmp(app.CurrentMode, 'EarliestLatest')
                app.currentIndex = numel(app.EarliestLatestSubset);
            else
                app.currentIndex = numel(app.AlignedImages);
            end

            app.updateImageDisplay();
        end

        % Value changed function: ModeSlider
        function ModeSliderValueChanged(app, event)
            app.ModeSlider.Value = event.Value;
            app.updateSliderBlend();
            
        end

        % Value changing function: ModeSlider
        function ModeSliderValueChanging(app, event)
            app.ModeSlider.Value = event.Value;
            app.updateSliderBlend();
            
        end

        % Value changed function: PreSwitch
        function PreSwitchValueChanged(app, event)
            val = app.PreSwitch.Value;
            if strcmp(val, 'On')
                app.PreprocessingEnabled = true;
                disp('Preprocessing ENABLED');
            else
                app.PreprocessingEnabled = false;
                disp('Preprocessing DISABLED');
            end
        end

        % Button pushed function: SizeButton
        function SizeButtonPushed(app, event)
            app.CurrentMode = 'Size';
            app.CurrentDetectionMode = 'size';
            app.showModeControls('Size');
            app.showDetectionResult('size');
        end

        % Button pushed function: LandUseButton
        function LandUseButtonPushed(app, event)
            app.CurrentMode = 'LandUse';
            app.CurrentDetectionMode = 'landuse';
            app.showModeControls('LandUse');
            app.showDetectionResult('landuse');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Image Display')
            app.UIAxes.Tag = 'DisplayAxes';
            app.MenuPanel = uipanel(app.UIFigure);
            app.MenuPanel.Title = 'Menu';
            app.MenuPanel.Tag = 'Menu';
            app.GridLayout2 = uigridlayout(app.MenuPanel);
            app.EarliestDropDownLabel = uilabel(app.GridLayout2);
            app.EarliestDropDownLabel.Text = 'Earliest';
            app.EarliestDropDown = uidropdown(app.GridLayout2);
            app.EarliestDropDown.ValueChangedFcn = createCallbackFcn(app, @EarliestDropDownValueChanged, true);
            app.EarliestDropDown.Tag = 'EarliestDropDown';
            app.LatestDropDownLabel = uilabel(app.GridLayout2);
            app.LatestDropDownLabel.Text = 'Latest ';
            app.LatestDropDown = uidropdown(app.GridLayout2);
            app.LatestDropDown.ValueChangedFcn = createCallbackFcn(app, @LatestDropDownValueChanged, true);
            app.LatestDropDown.Tag = 'LatestDropDown';
            app.AnalyzeButton = uibutton(app.GridLayout2, 'push');
            app.AnalyzeButton.ButtonPushedFcn = createCallbackFcn(app, @AnalyzeButtonPushed, true);
            app.AnalyzeButton.Tag = 'AnalyzeButton';
            app.AnalyzeButton.Text = 'Analyze';
            app.Panel_2 = uipanel(app.UIFigure);
            app.GridLayout3 = uigridlayout(app.Panel_2);
            app.ViewButton = uibutton(app.GridLayout3, 'push');
            app.ViewButton.ButtonPushedFcn = createCallbackFcn(app, @ViewButtonPushed, true);
            app.ViewButton.Tag = 'EarliestButton';
            app.ViewButton.Text = 'View';
            app.SliderButton = uibutton(app.GridLayout3, 'push');
            app.SliderButton.ButtonPushedFcn = createCallbackFcn(app, @SliderButtonPushed, true);
            app.SliderButton.Tag = 'SliderButton';
            app.SliderButton.Text = 'Slider';
            app.TimelapseButton = uibutton(app.GridLayout3, 'push');
            app.TimelapseButton.ButtonPushedFcn = createCallbackFcn(app, @TimelapseButtonPushed, true);
            app.TimelapseButton.Tag = 'TimelapseButton';
            app.TimelapseButton.Text = 'Time-lapse';
            app.AbsoluteButton = uibutton(app.GridLayout3, 'push');
            app.AbsoluteButton.ButtonPushedFcn = createCallbackFcn(app, @AbsoluteButtonPushed, true);
            app.AbsoluteButton.Tag = 'OverlayButton';
            app.AbsoluteButton.Text = 'Absolute';
            app.SpeedButton = uibutton(app.GridLayout3, 'push');
            app.SpeedButton.ButtonPushedFcn = createCallbackFcn(app, @SpeedButtonPushed, true);
            app.SpeedButton.Tag = 'DifferenceButton';
            app.SpeedButton.Text = 'Speed';
            app.SizeButton = uibutton(app.GridLayout3, 'push');
            app.SizeButton.ButtonPushedFcn = createCallbackFcn(app, @SizeButtonPushed, true);
            app.SizeButton.Tag = 'LatestButton';
            app.SizeButton.Text = 'Size';
            app.LandUseButton = uibutton(app.GridLayout3, 'push');
            app.LandUseButton.ButtonPushedFcn = createCallbackFcn(app, @LandUseButtonPushed, true);
            app.LandUseButton.Text = 'Land Use';
            app.ControlPanel = uipanel(app.UIFigure);
            app.ControlPanel.Tag = 'ControlPanel';
            app.ColorModeLabel = uilabel(app.ControlPanel);
            app.ColorModeLabel.Visible = 'off';
            app.ColorModeLabel.Text = 'Color Mode';
            app.ColorModeDropDown = uidropdown(app.ControlPanel);
            app.ColorModeDropDown.Items = {'Gray', 'RGB', 'R', 'G', 'B'};
            app.ColorModeDropDown.Visible = 'off';
            app.ColorModeDropDown.Value = 'Gray';
            app.Label = uilabel(app.ControlPanel);
            app.Label.Text = '';
            app.ModeSlider = uislider(app.ControlPanel);
            app.ModeSlider.ValueChangedFcn = createCallbackFcn(app, @ModeSliderValueChanged, true);
            app.ModeSlider.ValueChangingFcn = createCallbackFcn(app, @ModeSliderValueChanging, true);
            app.ModeSlider.Visible = 'off';
            app.PlayButton = uibutton(app.ControlPanel, 'push');
            app.PlayButton.Visible = 'off';
            app.PlayButton.Text = 'Play';
            app.OutputButton = uibutton(app.ControlPanel, 'push');
            app.OutputButton.Text = 'Output';
            app.LatestButton = uibutton(app.ControlPanel, 'push');
            app.LatestButton.ButtonPushedFcn = createCallbackFcn(app, @LatestButtonPushed, true);
            app.LatestButton.Visible = 'off';
            app.LatestButton.Text = 'Latest';
            app.NextButton = uibutton(app.ControlPanel, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Visible = 'off';
            app.NextButton.Text = 'Next';
            app.PreviousButton = uibutton(app.ControlPanel, 'push');
            app.PreviousButton.ButtonPushedFcn = createCallbackFcn(app, @PreviousButtonPushed, true);
            app.PreviousButton.Visible = 'off';
            app.PreviousButton.Text = 'Previous';
            app.EarliestButton = uibutton(app.ControlPanel, 'push');
            app.EarliestButton.ButtonPushedFcn = createCallbackFcn(app, @EarliestButtonPushed, true);
            app.EarliestButton.Visible = 'off';
            app.EarliestButton.Text = 'Earliest';
            app.Label_2 = uilabel(app.ControlPanel);
            app.Label_2.Visible = 'off';
            app.Label_2.Text = '';
            app.PageIndicatorField = uitextarea(app.ControlPanel);
            app.PageIndicatorField.Editable = 'off';
            app.PageIndicatorField.Visible = 'off';
            app.BrowseButton = uibutton(app.UIFigure, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Tag = 'ImportButton';
            app.BrowseButton.Text = 'Browse';
            app.EditFieldTextAreaLabel = uilabel(app.UIFigure);
            app.EditFieldTextAreaLabel.Text = 'Edit Field';
            app.EditFieldTextArea = uitextarea(app.UIFigure);
            app.EditFieldTextArea.Editable = 'off';
            app.PreSwitchLabel = uilabel(app.UIFigure);
            app.PreSwitchLabel.Text = 'Pre';
            app.PreSwitch = uiswitch(app.UIFigure, 'slider');
            app.PreSwitch.ValueChangedFcn = createCallbackFcn(app, @PreSwitchValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end